classdef App < matlab.apps.AppBase
    %APP Transition and circular curve setter
    %   An app to set transition and circular curve. 

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        maxLabel_2                      matlab.ui.control.Label
        Superelevationfor1mroadwidtheEditField  matlab.ui.control.NumericEditField
        e_maxLabel                      matlab.ui.control.Label
        DeflectionAngleEditField        matlab.ui.control.NumericEditField
        DeflectionAngleEditFieldLabel   matlab.ui.control.Label
        RoadWidthEditField              matlab.ui.control.NumericEditField
        RoadWidthEditFieldLabel         matlab.ui.control.Label
        DesignSpeedEditField            matlab.ui.control.NumericEditField
        DesignSpeedEditFieldLabel       matlab.ui.control.Label
        SetthecurveButton               matlab.ui.control.Button
        meterLabel                      matlab.ui.control.Label
        kmhLabel                        matlab.ui.control.Label
        degreeLabel                     matlab.ui.control.Label
        OutputTab                       matlab.ui.container.Tab
        UpdateButton                    matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        BasicDetailsTab                 matlab.ui.container.Tab
        ShowDetailsofCircularCurveButton  matlab.ui.control.Button
        ShowDetailsofTransitionCurveButton  matlab.ui.control.Button
        RadiusofCurve00mLabel           matlab.ui.control.Label
        CurveDescriptionLabel           matlab.ui.control.Label
        LenghtofTransitionCurve00mLabel  matlab.ui.control.Label
        DeflectionAngle00degreeLabel    matlab.ui.control.Label
        DesignSpeed00kmhLabel           matlab.ui.control.Label
        e000Label                       matlab.ui.control.Label
        Maximumsidefrictionfactor000Label  matlab.ui.control.Label
        Maximumsuperelevation000Label   matlab.ui.control.Label
        maxLabel                        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        DetailsofTransitionCurveTab     matlab.ui.container.Tab
        ChordLengthUsedEditField        matlab.ui.control.NumericEditField
        ChordLengthLabel                matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        TransitionCurveDetailsLabel     matlab.ui.control.Label
        ExportDatastoExcelButton        matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DetailsofCircularCurveTab       matlab.ui.container.Tab
        RankinesMethodofTangentialDeflectionAngleLabel  matlab.ui.control.Label
        MethodUsedLabel                 matlab.ui.control.Label
        ChordCountEditField             matlab.ui.control.NumericEditField
        ChordCountEditFieldLabel        matlab.ui.control.Label
        CircularCurveDetailsLabel       matlab.ui.control.Label
        ExportDatasToExcelButton        matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        DecidedLengthEditField          matlab.ui.control.NumericEditField
        DecidedLengthEditFieldLabel     matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        Lengthwithradialaccelerationmethod00mLabel  matlab.ui.control.Label
        Lengthwitharbitratygradientmethod00mLabel  matlab.ui.control.Label
        RateofchangeofradialaccelerationEditField  matlab.ui.control.NumericEditField
        RateofchangeofradialaccelerationEditFieldLabel  matlab.ui.control.Label
        ms3Label                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        Arbitrarygradient1inEditField   matlab.ui.control.NumericEditField
        Arbitrarygradient1inEditFieldLabel  matlab.ui.control.Label
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        MethodofRateofChangeofRadialAccelerationButton  matlab.ui.control.RadioButton
        MethodofArbitraryGradientButton  matlab.ui.control.RadioButton
        CombinedButton                  matlab.ui.control.RadioButton
        DecidedRadiusEditField_3        matlab.ui.control.NumericEditField
        DecidedRadiusLabel              matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        LenghtofTransitionCurveParametersLabel  matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        Radiusiffrictioniszero00mLabel  matlab.ui.control.Label
        Minimumradius00mLabel           matlab.ui.control.Label
        RadiusParametersLabel           matlab.ui.control.Label
        Group06Label                    matlab.ui.control.Label
        CE206EngineeringComputationSessionalLabel  matlab.ui.control.Label
        TransitionandCircularCurveSetterLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        ds = 0;  % Design Speed
        da = 0;  % Deflection angle
        rw = 0;  % Road width
        emax = 0.08;% e_max
        rracc = 0.3;% rate of change of radial acceleration
        fmax = 0; 
        hmax = 0;
        Rmin = 0;
        Rfzero = 0; % Radius when f is zero
        R = 0;
        onein = 100; % arbitrary gradient
        C = 0.3; % Rate of change in radial acceleration
        L = 0; % Length of transition Curve
        Lacc = 0; % Length in rad acc method
        Lgrad = 0; % length in arbitrary gradient method
        y = 0;
        alphad = 0;
        x = 0;
        chordlen = 0;
        tandang = 0;
        l = [];
        tcl = 5;
        ccc = 18;
    end
    
    methods (Access = private)
        
        function transitionCurveTable(app)
            [pegs, app.l, ~, app.alphad, app.y, supelv] = settranscurve(app.tcl, app.R, app.L, app.hmax); %% chord lenght to be added
            mat = [pegs(:) app.l(:) app.alphad(:) app.y(:) supelv(:)];
            app.UITable.Data = mat;

        end
        
        function circularCurveTable(app)
            [chord, app.tandang, cumarclen, app.chordlen] =  setcircle(app.ccc, app.L, app.R, app.da); %% Chord count should be adjusted
            mat = [chord(:) app.tandang(:) app.chordlen*ones(length(chord), 1) cumarclen(:)];
            app.UITable2.Data = mat;
        end
        
        function  setDecidedlengthlimit(app)
            if app.Lacc > app.Lgrad
                app.DecidedLengthEditField.Limits = [app.Lgrad app.Lacc];
            else
                app.DecidedLengthEditField.Limits = [app.Lacc app.Lgrad];
            end
        end
        
        function drawPlot(app)
            hold(app.UIAxes, "off");
            set(app.UIAxes, 'color', "#97FA8B");
            axis(app.UIAxes, "equal")
            app.x = app.y./tand(app.alphad);
            plot(app.UIAxes, app.x, app.y, 'color', '#6F6F6F', LineWidth=round(app.rw/4));
            hold(app.UIAxes, "on");
            angle = app.alphad(end) * 3;
            dangle = app.tandang(1) * 2;
            xx = zeros([1, length(app.tandang)*2]);
            yy = zeros(size(xx));
            xx = [app.x(end) xx];
            yy = [app.y(end) yy];
            for i = 1:length(app.tandang)*2
                angle = angle + dangle;
                xx(i+1) = xx(i) + app.chordlen*cosd(angle);
                yy(i+1) = yy(i) + app.chordlen*sind(angle);
            end
            plot(app.UIAxes, xx, yy, 'color', '#404141', LineWidth=round(app.rw/4));
            xxx = zeros([1, length(app.alphad)]);
            yyy = zeros(size(xxx));
            xxx = [xx(end) xxx];
            yyy = [yy(end) yyy];
            for i = 1:length(app.alphad)
                angle = app.da - app.alphad(end - i + 1)*3;
                xxx(i+1) = xxx(i) + app.l(1)*cosd(angle);
                yyy(i+1) = yyy(i) + app.l(1)*sind(angle);
            end
            plot(app.UIAxes, xxx, yyy, 'color', '#6F6F6F', LineWidth=round(app.rw/4));
            title(app.UIAxes, "The Curve");
        end
        
        function results = checkL(app, pendL)
            if pendL/app.R/pi*180 > app.da
                results = 0;
            else
                results = 1;
            end
        end
        
        function results = checkR(app, pendR)
            if app.L/pendR/pi*180 > app.da
                results = 0;
            else
                results = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetthecurveButton
        function SetthecurveButtonPushed(app, event)
            app.da = app.DeflectionAngleEditField.Value;
            app.ds = app.DesignSpeedEditField.Value;
            app.rw = app.RoadWidthEditField.Value;
            app.emax = app.Superelevationfor1mroadwidtheEditField.Value;
            app.fmax = decidefmax(app.ds);
            app.DesignSpeed00kmhLabel.Text = "Design Speed = " + num2str(app.ds) + " km/h";
            app.DeflectionAngle00degreeLabel.Text = "Deflection Angle = " + num2str(app.da) + " degree";
            app.Maximumsidefrictionfactor000Label.Text = "Maximum Side friction factor = " + num2str(app.fmax);
            app.e000Label.Text = "e     = " + num2str(app.emax);
            app.hmax = decidehmax(app.rw, app.emax);
            app.Maximumsuperelevation000Label.Text = "Maximum super-elevation = " + num2str(app.hmax) + " m";
            [app.R, app.Rmin, app.Rfzero] = decideR(app.ds, app.emax, app.fmax);
            app.Minimumradius00mLabel.Text = "Minimum radius = " + num2str(app.Rmin) + " m";
            app.Radiusiffrictioniszero00mLabel.Text = "Radius if friction is zero = " + num2str(app.Rfzero) + " m";
            app.DecidedRadiusEditField_3.Value = app.R;
            app.RadiusofCurve00mLabel.Text = "Radius of Curve = " + num2str(app.R) + " m";
            [app.L, app.Lacc, app.Lgrad] = decideL(app.hmax, app.onein, app.C, app.ds, app.R);
            app.Lengthwitharbitratygradientmethod00mLabel.Text = "Length with arbitrary gradient method = " + num2str(app.Lgrad) + " m";
            app.Lengthwithradialaccelerationmethod00mLabel.Text = "Length with radial acceleration method = " + num2str(app.Lacc) + " m";
            app.DecidedLengthEditField.Value = app.L;
            app.LenghtofTransitionCurve00mLabel.Text = "Length of Transition Curve = " + num2str(app.L) + " m";
            app.transitionCurveTable();
            app.circularCurveTable();
            app.DecidedRadiusEditField_3.Limits = [app.Rmin app.Rfzero];
            app.setDecidedlengthlimit();
            app.drawPlot();
            pause(0.1);
            app.TabGroup.SelectedTab = app.OutputTab;
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            if app.CombinedButton.Value
                app.Arbitrarygradient1inEditField.Enable = "on";
                app.RateofchangeofradialaccelerationEditField.Enable = "on";
                app.DecidedLengthEditField.Editable = "on";
                app.setDecidedlengthlimit();

                app.Arbitrarygradient1inEditField.Enable = "on";
                app.mLabel_2.Enable = "on";
                app.RateofchangeofradialaccelerationEditFieldLabel.Enable = "on";
                app.ms3Label.Enable = "on";
                app.Lengthwitharbitratygradientmethod00mLabel.Enable = "on";
                app.Lengthwithradialaccelerationmethod00mLabel.Enable = "on";

            elseif app.MethodofArbitraryGradientButton.Value
                app.Arbitrarygradient1inEditField.Enable = "on";
                app.RateofchangeofradialaccelerationEditField.Enable = "off";
                app.DecidedLengthEditField.Editable = "off";
                app.DecidedLengthEditField.Value = app.Lgrad;
               
                app.Arbitrarygradient1inEditField.Enable = "on";
                app.mLabel_2.Enable = "on";
                app.RateofchangeofradialaccelerationEditFieldLabel.Enable = "off";
                app.ms3Label.Enable = "off";
                app.Lengthwitharbitratygradientmethod00mLabel.Enable = "on";
                app.Lengthwithradialaccelerationmethod00mLabel.Enable = "off";

            else
                app.Arbitrarygradient1inEditField.Enable = "off";
                app.RateofchangeofradialaccelerationEditField.Enable = "on";
                app.DecidedLengthEditField.Editable = "off";
                app.DecidedLengthEditField.Value = app.Lacc;

                app.Arbitrarygradient1inEditField.Enable = "off";
                app.mLabel_2.Enable = "off";
                app.RateofchangeofradialaccelerationEditFieldLabel.Enable = "on";
                app.ms3Label.Enable = "on";
                app.Lengthwitharbitratygradientmethod00mLabel.Enable = "off";
                app.Lengthwithradialaccelerationmethod00mLabel.Enable = "on";

            end
           
        end

        % Value changed function: Arbitrarygradient1inEditField
        function Arbitrarygradient1inEditFieldValueChanged(app, event)
            value = app.Arbitrarygradient1inEditField.Value;
            app.Lgrad = LofTcurveArbGrad(app.hmax, value);
            app.Lengthwitharbitratygradientmethod00mLabel.Text = "Length with arbitrary gradient method = " + num2str(app.Lgrad) + " m";
            app.onein = value;
            app.setDecidedlengthlimit();
        end

        % Value changed function: RateofchangeofradialaccelerationEditField
        function RateofchangeofradialaccelerationEditFieldValueChanged(app, event)
            value = app.RateofchangeofradialaccelerationEditField.Value;
            app.Lacc = LofTcurverateofchangeofradacc(value, app.ds, app.R);
            app.Lengthwithradialaccelerationmethod00mLabel.Text = "Length with radial acceleration method = " + num2str(app.Lacc) + " m";
            app.C = value;
            app.setDecidedlengthlimit();
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.L = app.DecidedLengthEditField.Value;
            app.RadiusofCurve00mLabel.Text = "Radius of Curve = " + num2str(app.R) + " m";
            app.LenghtofTransitionCurve00mLabel.Text = "Length of Transition Curve = " + num2str(app.L) + " m";
            app.circularCurveTable();
            app.transitionCurveTable();
            app.drawPlot();
        end

        % Value changed function: DecidedRadiusEditField_3
        function DecidedRadiusEditField_3ValueChanged(app, event)
            value = app.DecidedRadiusEditField_3.Value;
            if app.checkR(value) == 1
                app.R = value;
            else
                app.DecidedRadiusEditField_3.Value = app.R;
                msgbox("The Radius that you are trying to choose results in overlapping of transition curve of both sides. Try increasing the value of 'Decided Radius'", "Error");
                
            end
        end

        % Value changed function: DecidedLengthEditField
        function DecidedLengthEditFieldValueChanged(app, event)
            value = app.DecidedLengthEditField.Value;
            if app.checkL(value) == 1
                app.L = value;
            else
                app.DecidedLengthEditField.Value = app.L;
                msgbox("The length that you are trying to choose results in overlapping of transition curve of both sides. Try reducing the value of 'Decided Length'", "Error");
                
            end
            
        end

        % Value changed function: ChordLengthUsedEditField
        function ChordLengthUsedEditFieldValueChanged(app, event)
            app.tcl = app.ChordLengthUsedEditField.Value;
            app.transitionCurveTable();
            app.circularCurveTable();
            app.drawPlot();
        end

        % Value changed function: ChordCountEditField
        function ChordCountEditFieldValueChanged(app, event)
            app.ccc = app.ChordCountEditField.Value;
            app.transitionCurveTable();
            app.circularCurveTable();
            app.drawPlot();
        end

        % Button pushed function: ShowDetailsofTransitionCurveButton
        function ShowDetailsofTransitionCurveButtonPushed(app, event)
            app.TabGroup2.SelectedTab = app.DetailsofTransitionCurveTab;
        end

        % Button pushed function: ShowDetailsofCircularCurveButton
        function ShowDetailsofCircularCurveButtonPushed(app, event)
            app.TabGroup2.SelectedTab = app.DetailsofCircularCurveTab;
        end

        % Button pushed function: ExportDatasToExcelButton, 
        % ...and 1 other component
        function ExportDatastoExcelButtonPushed(app, event)
            T1 = array2table(app.UITable.Data);
            T1.Properties.VariableNames(1:5) = app.UITable.ColumnName;
            writetable(T1, "Datas.xlsx", "Sheet", "Transition Curve");
            T2 = array2table(app.UITable2.Data);
            T2.Properties.VariableNames(1:4) = app.UITable2.ColumnName;
            writetable(T2, "Datas.xlsx", "Sheet", "Circular Curve");
            winopen("Datas.xlsx");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransitionandCircularCurveSetterLabel = uilabel(app.UIFigure);
            app.TransitionandCircularCurveSetterLabel.Text = 'Transition and Circular Curve Setter';
            app.CE206EngineeringComputationSessionalLabel = uilabel(app.UIFigure);
            app.CE206EngineeringComputationSessionalLabel.Text = 'CE 206 : Engineering Computation Sessional';
            app.Group06Label = uilabel(app.UIFigure);
            app.Group06Label.Text = 'Group : 06';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.degreeLabel = uilabel(app.InputTab);
            app.degreeLabel.Text = 'degree';
            app.kmhLabel = uilabel(app.InputTab);
            app.kmhLabel.Text = 'km/h';
            app.meterLabel = uilabel(app.InputTab);
            app.meterLabel.Text = 'meter';
            app.SetthecurveButton = uibutton(app.InputTab, 'push');
            app.SetthecurveButton.ButtonPushedFcn = createCallbackFcn(app, @SetthecurveButtonPushed, true);
            app.SetthecurveButton.Text = 'Set the curve';
            app.DesignSpeedEditFieldLabel = uilabel(app.InputTab);
            app.DesignSpeedEditFieldLabel.Text = 'Design Speed : ';
            app.DesignSpeedEditField = uieditfield(app.InputTab, 'numeric');
            app.DesignSpeedEditField.Limits = [35 100];
            app.DesignSpeedEditField.Value = 70;
            app.RoadWidthEditFieldLabel = uilabel(app.InputTab);
            app.RoadWidthEditFieldLabel.Text = 'Road Width : ';
            app.RoadWidthEditField = uieditfield(app.InputTab, 'numeric');
            app.RoadWidthEditField.Limits = [6 Inf];
            app.RoadWidthEditField.Value = 6;
            app.DeflectionAngleEditFieldLabel = uilabel(app.InputTab);
            app.DeflectionAngleEditFieldLabel.Text = 'Deflection Angle : ';
            app.DeflectionAngleEditField = uieditfield(app.InputTab, 'numeric');
            app.DeflectionAngleEditField.Limits = [20 180];
            app.DeflectionAngleEditField.ValueDisplayFormat = '%.2f';
            app.DeflectionAngleEditField.Value = 45;
            app.e_maxLabel = uilabel(app.InputTab);
            app.e_maxLabel.Text = ' Super-elevation for 1 m road width ,e       :';
            app.Superelevationfor1mroadwidtheEditField = uieditfield(app.InputTab, 'numeric');
            app.Superelevationfor1mroadwidtheEditField.Limits = [0.01 10];
            app.Superelevationfor1mroadwidtheEditField.Value = 0.08;
            app.maxLabel_2 = uilabel(app.InputTab);
            app.maxLabel_2.Text = 'max';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.RadiusParametersLabel = uilabel(app.OutputTab);
            app.RadiusParametersLabel.Text = 'Radius Parameters';
            app.Minimumradius00mLabel = uilabel(app.OutputTab);
            app.Minimumradius00mLabel.Text = 'Minimum radius = 00 m         ';
            app.Radiusiffrictioniszero00mLabel = uilabel(app.OutputTab);
            app.Radiusiffrictioniszero00mLabel.Text = 'Radius if friction is zero = 00 m         ';
            app.mLabel = uilabel(app.OutputTab);
            app.mLabel.Text = 'm';
            app.LenghtofTransitionCurveParametersLabel = uilabel(app.OutputTab);
            app.LenghtofTransitionCurveParametersLabel.Text = 'Lenght of Transition Curve Parameters';
            app.ListBox = uilistbox(app.OutputTab);
            app.ListBox.Items = {'', '', '', ''};
            app.ListBox.Value = '';
            app.DecidedRadiusLabel = uilabel(app.OutputTab);
            app.DecidedRadiusLabel.Text = 'Decided Radius = ';
            app.DecidedRadiusEditField_3 = uieditfield(app.OutputTab, 'numeric');
            app.DecidedRadiusEditField_3.ValueChangedFcn = createCallbackFcn(app, @DecidedRadiusEditField_3ValueChanged, true);
            app.MethodButtonGroup = uibuttongroup(app.OutputTab);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = ' Method';
            app.CombinedButton = uiradiobutton(app.MethodButtonGroup);
            app.CombinedButton.Text = 'Combined';
            app.CombinedButton.Value = true;
            app.MethodofArbitraryGradientButton = uiradiobutton(app.MethodButtonGroup);
            app.MethodofArbitraryGradientButton.Text = 'Method of Arbitrary Gradient';
            app.MethodofRateofChangeofRadialAccelerationButton = uiradiobutton(app.MethodButtonGroup);
            app.MethodofRateofChangeofRadialAccelerationButton.Text = 'Method of Rate of Change of Radial Acceleration';
            app.Arbitrarygradient1inEditFieldLabel = uilabel(app.OutputTab);
            app.Arbitrarygradient1inEditFieldLabel.Text = 'Arbitrary gradient = 1 in ';
            app.Arbitrarygradient1inEditField = uieditfield(app.OutputTab, 'numeric');
            app.Arbitrarygradient1inEditField.ValueChangedFcn = createCallbackFcn(app, @Arbitrarygradient1inEditFieldValueChanged, true);
            app.Arbitrarygradient1inEditField.Value = 100;
            app.mLabel_2 = uilabel(app.OutputTab);
            app.mLabel_2.Text = 'm';
            app.ms3Label = uilabel(app.OutputTab);
            app.ms3Label.Text = 'm/s^3';
            app.RateofchangeofradialaccelerationEditFieldLabel = uilabel(app.OutputTab);
            app.RateofchangeofradialaccelerationEditFieldLabel.Text = 'Rate of change of radial acceleration = ';
            app.RateofchangeofradialaccelerationEditField = uieditfield(app.OutputTab, 'numeric');
            app.RateofchangeofradialaccelerationEditField.ValueChangedFcn = createCallbackFcn(app, @RateofchangeofradialaccelerationEditFieldValueChanged, true);
            app.RateofchangeofradialaccelerationEditField.Value = 0.3;
            app.Lengthwitharbitratygradientmethod00mLabel = uilabel(app.OutputTab);
            app.Lengthwitharbitratygradientmethod00mLabel.Text = 'Length with arbitraty gradient method = 00 m';
            app.Lengthwithradialaccelerationmethod00mLabel = uilabel(app.OutputTab);
            app.Lengthwithradialaccelerationmethod00mLabel.Text = 'Length with radial acceleration method = 00 m';
            app.mLabel_3 = uilabel(app.OutputTab);
            app.mLabel_3.Text = 'm';
            app.DecidedLengthEditFieldLabel = uilabel(app.OutputTab);
            app.DecidedLengthEditFieldLabel.Text = 'Decided Length = ';
            app.DecidedLengthEditField = uieditfield(app.OutputTab, 'numeric');
            app.DecidedLengthEditField.ValueChangedFcn = createCallbackFcn(app, @DecidedLengthEditFieldValueChanged, true);
            app.TabGroup2 = uitabgroup(app.OutputTab);
            app.BasicDetailsTab = uitab(app.TabGroup2);
            app.BasicDetailsTab.Title = 'Basic Details';
            app.UIAxes = uiaxes(app.BasicDetailsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.maxLabel = uilabel(app.BasicDetailsTab);
            app.maxLabel.Text = 'max';
            app.Maximumsuperelevation000Label = uilabel(app.BasicDetailsTab);
            app.Maximumsuperelevation000Label.Text = 'Maximum super-elevation = 0.00';
            app.Maximumsidefrictionfactor000Label = uilabel(app.BasicDetailsTab);
            app.Maximumsidefrictionfactor000Label.Text = 'Maximum side friction factor = 0.00';
            app.e000Label = uilabel(app.BasicDetailsTab);
            app.e000Label.Text = 'e     = 0.00';
            app.DesignSpeed00kmhLabel = uilabel(app.BasicDetailsTab);
            app.DesignSpeed00kmhLabel.Text = 'Design Speed = 00 km/h';
            app.DeflectionAngle00degreeLabel = uilabel(app.BasicDetailsTab);
            app.DeflectionAngle00degreeLabel.Text = 'Deflection Angle = 00 degree';
            app.LenghtofTransitionCurve00mLabel = uilabel(app.BasicDetailsTab);
            app.LenghtofTransitionCurve00mLabel.Text = 'Lenght of Transition Curve = 00 m';
            app.CurveDescriptionLabel = uilabel(app.BasicDetailsTab);
            app.CurveDescriptionLabel.Text = 'Curve Description';
            app.RadiusofCurve00mLabel = uilabel(app.BasicDetailsTab);
            app.RadiusofCurve00mLabel.Text = 'Radius of Curve = 00 m           ';
            app.ShowDetailsofTransitionCurveButton = uibutton(app.BasicDetailsTab, 'push');
            app.ShowDetailsofTransitionCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDetailsofTransitionCurveButtonPushed, true);
            app.ShowDetailsofTransitionCurveButton.Text = 'Show Details of Transition Curve';
            app.ShowDetailsofCircularCurveButton = uibutton(app.BasicDetailsTab, 'push');
            app.ShowDetailsofCircularCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDetailsofCircularCurveButtonPushed, true);
            app.ShowDetailsofCircularCurveButton.Text = 'Show Details of Circular Curve';
            app.DetailsofTransitionCurveTab = uitab(app.TabGroup2);
            app.DetailsofTransitionCurveTab.Title = 'Details of Transition Curve';
            app.UITable = uitable(app.DetailsofTransitionCurveTab);
            app.UITable.ColumnName = {'Peg Station No.'; 'Length Cover (m)'; 'Deflection Angle from point of Curve (degree)'; 'Perpendicular distance from back tangent, y (m)'; 'Super Elevation (m)'};
            app.UITable.RowName = {};
            app.ExportDatastoExcelButton = uibutton(app.DetailsofTransitionCurveTab, 'push');
            app.ExportDatastoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatastoExcelButtonPushed, true);
            app.ExportDatastoExcelButton.Text = 'Export Datas to Excel';
            app.TransitionCurveDetailsLabel = uilabel(app.DetailsofTransitionCurveTab);
            app.TransitionCurveDetailsLabel.Text = 'Transition Curve Details';
            app.mLabel_4 = uilabel(app.DetailsofTransitionCurveTab);
            app.mLabel_4.Text = 'm';
            app.ChordLengthLabel = uilabel(app.DetailsofTransitionCurveTab);
            app.ChordLengthLabel.Text = 'Chord Length Used =';
            app.ChordLengthUsedEditField = uieditfield(app.DetailsofTransitionCurveTab, 'numeric');
            app.ChordLengthUsedEditField.Limits = [2 6];
            app.ChordLengthUsedEditField.ValueChangedFcn = createCallbackFcn(app, @ChordLengthUsedEditFieldValueChanged, true);
            app.ChordLengthUsedEditField.Value = 5;
            app.DetailsofCircularCurveTab = uitab(app.TabGroup2);
            app.DetailsofCircularCurveTab.Title = 'Details of Circular Curve';
            app.UITable2 = uitable(app.DetailsofCircularCurveTab);
            app.UITable2.ColumnName = {'Chord'; 'Tangential Deflection angle (degree)'; 'Chord Lenght (m)'; 'Cumulative Arc Lenght (m)'};
            app.UITable2.RowName = {};
            app.ExportDatasToExcelButton = uibutton(app.DetailsofCircularCurveTab, 'push');
            app.ExportDatasToExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDatastoExcelButtonPushed, true);
            app.ExportDatasToExcelButton.Text = ' Export DatasTo Excel';
            app.CircularCurveDetailsLabel = uilabel(app.DetailsofCircularCurveTab);
            app.CircularCurveDetailsLabel.Text = 'Circular Curve Details';
            app.ChordCountEditFieldLabel = uilabel(app.DetailsofCircularCurveTab);
            app.ChordCountEditFieldLabel.Text = 'Chord Count =';
            app.ChordCountEditField = uieditfield(app.DetailsofCircularCurveTab, 'numeric');
            app.ChordCountEditField.Limits = [15 Inf];
            app.ChordCountEditField.ValueChangedFcn = createCallbackFcn(app, @ChordCountEditFieldValueChanged, true);
            app.ChordCountEditField.Value = 18;
            app.MethodUsedLabel = uilabel(app.DetailsofCircularCurveTab);
            app.MethodUsedLabel.Text = 'Method Used : ';
            app.RankinesMethodofTangentialDeflectionAngleLabel = uilabel(app.DetailsofCircularCurveTab);
            app.RankinesMethodofTangentialDeflectionAngleLabel.Text = 'Rankine''s Method of Tangential Deflection Angle ';
            app.UpdateButton = uibutton(app.OutputTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end